classdef greenroom < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        MintRoomUIFigure           matlab.ui.Figure
        MintRoomLabel              matlab.ui.control.Label
        SettingsPanel              matlab.ui.container.Panel
        TabGroup                   matlab.ui.container.TabGroup
        FrontSpeakersTab           matlab.ui.container.Tab
        FrontParametersPanel       matlab.ui.container.Panel
        GainLevelsPanel            matlab.ui.container.Panel
        RightSpeakerKnobLabel      matlab.ui.control.Label
        RightSpeakerKnob           matlab.ui.control.Knob
        LeftSpeakerKnob_2Label     matlab.ui.control.Label
        LeftSpeakerKnob            matlab.ui.control.Knob
        CentralSpeakerKnobLabel    matlab.ui.control.Label
        CentralSpeakerKnob         matlab.ui.control.Knob
        CentralActivationPanel     matlab.ui.container.Panel
        CentralSpeakerSwitchLabel  matlab.ui.control.Label
        CentralSpeakerSwitch       matlab.ui.control.Switch
        FrontAnglePanel            matlab.ui.container.Panel
        AngleSlider_9Label         matlab.ui.control.Label
        FrontAngleSlider           matlab.ui.control.Slider
        FrontMonitorPanel          matlab.ui.container.Panel
        FrontUIAxes                matlab.ui.control.UIAxes
        RearSpeaker1Tab            matlab.ui.container.Tab
        Rear1ParametersPanel       matlab.ui.container.Panel
        Rear1Panel1                matlab.ui.container.Panel
        ActivateSwitchLabel        matlab.ui.control.Label
        Rear1ActivateSwitch        matlab.ui.control.Switch
        Rear1Panel2                matlab.ui.container.Panel
        DelayKnobLabel             matlab.ui.control.Label
        Rear1DelayKnob             matlab.ui.control.Knob
        Rear1EditField             matlab.ui.control.NumericEditField
        Rear1Panel3                matlab.ui.container.Panel
        AngleSliderLabel           matlab.ui.control.Label
        Rear1AngleSlider           matlab.ui.control.Slider
        Rear1Switch                matlab.ui.control.Switch
        Rear1MonitorPanel          matlab.ui.container.Panel
        Rear1UIAxes                matlab.ui.control.UIAxes
        RearSpeaker2Tab            matlab.ui.container.Tab
        Rear2ParametersPanel       matlab.ui.container.Panel
        Rear2Panel1                matlab.ui.container.Panel
        ActivateSwitch_2Label      matlab.ui.control.Label
        Rear2ActivateSwitch        matlab.ui.control.Switch
        Rear2Panel2                matlab.ui.container.Panel
        DelayKnob_2Label           matlab.ui.control.Label
        Rear2DelayKnob             matlab.ui.control.Knob
        Rear2EditField             matlab.ui.control.NumericEditField
        Rear2Panel3                matlab.ui.container.Panel
        AngleSlider_2Label         matlab.ui.control.Label
        Rear2AngleSlider           matlab.ui.control.Slider
        Rear2Switch                matlab.ui.control.Switch
        Rear2MonitorPanel          matlab.ui.container.Panel
        Rear2UIAxes                matlab.ui.control.UIAxes
        RearSpeaker3Tab            matlab.ui.container.Tab
        Rear3ParametersPanel       matlab.ui.container.Panel
        Rear3Panel1                matlab.ui.container.Panel
        ActivateSwitch_3Label      matlab.ui.control.Label
        Rear3ActivateSwitch        matlab.ui.control.Switch
        Rear3Panel2                matlab.ui.container.Panel
        DelayKnob_3Label           matlab.ui.control.Label
        Rear3DelayKnob             matlab.ui.control.Knob
        Rear3EditField             matlab.ui.control.NumericEditField
        Rear3Panel3                matlab.ui.container.Panel
        AngleSlider_3Label         matlab.ui.control.Label
        Rear3AngleSlider           matlab.ui.control.Slider
        Rear3Switch                matlab.ui.control.Switch
        Rear3MonitorPanel          matlab.ui.container.Panel
        Rear3UIAxes                matlab.ui.control.UIAxes
        BinauralMixerPanel         matlab.ui.container.Panel
        ChannelPanel1              matlab.ui.container.Panel
        FrontSpeakersSliderLabel   matlab.ui.control.Label
        ChannelSlider1             matlab.ui.control.Slider
        Lamp1                      matlab.ui.control.Lamp
        MuteButton1                matlab.ui.control.StateButton
        Panel_2                    matlab.ui.container.Panel
        RearSpeaker1SliderLabel    matlab.ui.control.Label
        RearSpeaker1Slider         matlab.ui.control.Slider
        MuteButton2                matlab.ui.control.StateButton
        OnLamp_2                   matlab.ui.control.Lamp
        Panel_3                    matlab.ui.container.Panel
        RearSpeaker2SliderLabel    matlab.ui.control.Label
        RearSpeaker2Slider         matlab.ui.control.Slider
        OnLamp_3                   matlab.ui.control.Lamp
        MuteButton3                matlab.ui.control.StateButton
        Panel_4                    matlab.ui.container.Panel
        RearSpeaker3SliderLabel    matlab.ui.control.Label
        RearSpeaker3Slider         matlab.ui.control.Slider
        OnLamp_4                   matlab.ui.control.Lamp
        MuteButton4                matlab.ui.control.StateButton
        Panel_5                    matlab.ui.container.Panel
        MasterSliderLabel          matlab.ui.control.Label
        MasterSlider               matlab.ui.control.Slider
        OnLamp_5                   matlab.ui.control.Lamp
        MuteButton5                matlab.ui.control.StateButton
        FileControlPanel           matlab.ui.container.Panel
        PlayButton                 matlab.ui.control.Button
        StopButton                 matlab.ui.control.Button
        HelpButton                 matlab.ui.control.Button
        SaveFileButton             matlab.ui.control.Button
        SaveSettingsButton         matlab.ui.control.Button
        LoadFileButton             matlab.ui.control.Button
        FileLabel                  matlab.ui.control.Label
        FileEditField              matlab.ui.control.EditField
        BinauralUpmixerApplicationLabel  matlab.ui.control.Label
        LamprosChantzisAudiogroupofPatrasUniversityLabel  matlab.ui.control.Label
        UIAxes                     matlab.ui.control.UIAxes
        HelpPanel                  matlab.ui.container.Panel
        TextArea                   matlab.ui.control.TextArea
        GotitButton                matlab.ui.control.Button
    end

    
    properties (Access = private)
        Logo = imread('D:\Mint Room\Resources\Icons\logo.png'); % Logo
        Play = 0; % Player is on
        LoadFile = 0;
        
    end
    
    properties (Access = public)
        AudioFile  = zeros(8000,1); % WAV file that user loads in app
        StereoLeft = zeros(8000,1); % Left channel of stereo input
        StereoRight = zeros(8000,1); % Right channel of stereo input
        FL  = zeros(8000,1); % Front channel L
        FR  = zeros(8000,1); % Front channel R
        RL_1 = zeros(8000,1);
        RR_1 = zeros(8000,1);
        RL_2 = zeros(8000,1);
        RR_2 = zeros(8000,1);
        RL_3 = zeros(8000,1);
        RR_3 = zeros(8000,1);
        Lag1 = zeros(8000,1);
        Lag2 = zeros(8000,1);
        Lag3 = zeros(8000,1);
        FrontAngle0 = audioread('D:\Mint Room\Resources\HRTF Database\D1_HRIR_WAV\44K_16bit\azi_0,0_ele_0,0.wav'); % HRTF of front centre angle 
        FrontAngle1 = audioread('D:\Mint Room\Resources\HRTF Database\D1_HRIR_WAV\44K_16bit\azi_0,0_ele_0,0.wav'); % HRTF of front left angle
        FrontAngle2 = audioread('D:\Mint Room\Resources\HRTF Database\D1_HRIR_WAV\44K_16bit\azi_0,0_ele_0,0.wav'); % HRTF of front right angle
        RearAngle1 = audioread('D:\Mint Room\Resources\HRTF Database\D1_HRIR_WAV\44K_16bit\azi_90,0_ele_0,0.wav'); % HRTF of rear angle 1
        RearAngle2 = audioread('D:\Mint Room\Resources\HRTF Database\D1_HRIR_WAV\44K_16bit\azi_90,0_ele_0,0.wav'); % HRTF of rear angle 2
        RearAngle3 = audioread('D:\Mint Room\Resources\HRTF Database\D1_HRIR_WAV\44K_16bit\azi_90,0_ele_0,0.wav'); % HRTF of rear angle 3
        Channel2 % Mixer channel 2
        Channel3 % Mixer channel 3
        Channel4 % Mixer channel 4
        Master % Ouput channel 
        myplayer = []; % Audio player object
        Output % Output signal
    end
    
    methods (Access = private)
        
        function Fpremix(app)
            %sound(app.AudioFile,44100);
            % Front mix
            FL_1 = conv(app.StereoLeft, app.FrontAngle1(:,1));
            FR_1 = conv(app.StereoRight, app.FrontAngle1(:,2));
            FL_2 = conv(app.StereoLeft, app.FrontAngle2(:,1));
            FR_2 = conv(app.StereoRight, app.FrontAngle2(:,2));
            Gain_left = app.LeftSpeakerKnob.Value/10;
            Gain_right = app.RightSpeakerKnob.Value/10;
            if strcmpi('On', app.CentralSpeakerSwitch.Value)
                FL_0 = conv(app.StereoLeft, app.FrontAngle0(:,1));
                FR_0 = conv(app.StereoRight, app.FrontAngle0(:,2));
                Gain_central = app.CentralSpeakerKnob.Value/10;
                FrontL = FL_1 * Gain_left + FL_2 * Gain_right + FL_0 * Gain_central;
                FrontR = FR_1 * Gain_left + FR_2 * Gain_right + FR_0 * Gain_central;
            else
                FrontL = FL_1 * Gain_left + FL_2 * Gain_right;
                FrontR = FR_1 * Gain_left + FR_2 * Gain_right;
            end
            app.FL = FrontL * app.ChannelSlider1.Value/10;
            app.FR = FrontR * app.ChannelSlider1.Value/10;
            Rpremix1(app);
            Rpremix2(app);
            Rpremix3(app);
        end
    
        function Rpremix1(app)
            if all(app.OnLamp_2.Color == [0.3725 0.9804 0.7961])
                % Delay with FIR Filter
                % Delay = zeros(round(app.Rear1DelayKnob.Value) * 1000, 1);
                % b = [0.8;Delay;0.4;Delay;0.2;Delay;0.1];
                % app.Channel2 = filter(b,1,app.AudioFile);
                % Delay with Zero Padding
                reverb = ceil(3 * app.Rear1DelayKnob.Value);
                buffer1 = [zeros(reverb*200, 1); app.AudioFile(:,1); zeros(reverb*200,1)] * 0.5;
                buffer2 = [zeros(reverb*400, 1); app.AudioFile(:,1)] * 0.25;
                input = [app.AudioFile(:,1); zeros(reverb*400, 1)];
                app.Channel2 = input + buffer1 + buffer2;
                % Angle
                app.RL_1 = conv(app.Channel2, app.RearAngle1(:,1));
                app.RR_1 = conv(app.Channel2, app.RearAngle1(:,2));
                % Gain
                app.RL_1 = app.RL_1 * app.RearSpeaker1Slider.Value/10;
                app.RR_1 = app.RR_1 * app.RearSpeaker1Slider.Value/10;
            else
                app.RL_1 = zeros(8000,1);
                app.RR_1 = zeros(8000,1);
            end  
        end
    
        function Rpremix2(app)
            if all(app.OnLamp_3.Color == [0.3725 0.9804 0.7961])
                % Delay with FIR Filter
                % Delay = zeros(round(app.Rear2DelayKnob.Value) * 1000, 1);
                % b = [0.8;Delay;0.4;Delay;0.2;Delay;0.1];
                % app.Channel3 = filter(b,1,app.AudioFile);
                % Delay with Zero Padding
                reverb = ceil(3 * app.Rear2DelayKnob.Value);
                buffer1 = [zeros(reverb*200, 1); app.AudioFile(:,1); zeros(reverb*200,1)] * 0.5;
                buffer2 = [zeros(reverb*400, 1); app.AudioFile(:,1)] * 0.25;
                input = [app.AudioFile(:,1); zeros(reverb*400, 1)];
                app.Channel3 = input + buffer1 + buffer2;
                % Angle
                app.RL_2 = conv(app.Channel3, app.RearAngle2(:,1));
                app.RR_2 = conv(app.Channel3, app.RearAngle2(:,2));
                % Gain
                app.RL_2 = app.RL_2 * app.RearSpeaker2Slider.Value/10;
                app.RR_2 = app.RR_2 * app.RearSpeaker2Slider.Value/10;
            else
                app.RL_2 = zeros(8000,1);
                app.RR_2 = zeros(8000,1);
            end 
        end
    
        function Rpremix3(app)
            if all(app.OnLamp_4.Color == [0.3725 0.9804 0.7961])
                % Delay with FIR Filter
                % Delay = zeros(round(app.Rear3DelayKnob.Value) * 1000, 1);
                % b = [0.8;Delay;0.4;Delay;0.2;Delay;0.1];
                % app.Channel3 = filter(b,1,app.AudioFile);
                % Delay with Zero Padding
                reverb = ceil(3 * app.Rear3DelayKnob.Value);
                buffer1 = [zeros(reverb*200, 1); app.AudioFile(:,1); zeros(reverb*200,1)] * 0.5;
                buffer2 = [zeros(reverb*400, 1); app.AudioFile(:,1)] * 0.25;
                input = [app.AudioFile(:,1); zeros(reverb*400, 1)];
                app.Channel3 = input + buffer1 + buffer2;
                % Angle
                app.RL_3 = conv(app.Channel3, app.RearAngle3(:,1));
                app.RR_3 = conv(app.Channel3, app.RearAngle3(:,2));
                % Gain
                app.RL_3 = app.RL_3 * app.RearSpeaker3Slider.Value/10;
                app.RR_3 = app.RR_3 * app.RearSpeaker3Slider.Value/10;
            else
                app.RL_3 = zeros(8000,1);
                app.RR_3 = zeros(8000,1);
            end 
        end
        
        function Size_Modification(app)
            lag = max([length(app.FL), length(app.RL_1), length(app.RL_2), length(app.RL_3), length(app.RR_1), length(app.RR_2), length(app.RR_3)]);
            app.FL(length(app.FL)+1:lag) = 0;
            app.FR(length(app.FR)+1:lag) = 0;
            app.RL_1(length(app.RL_1)+1:lag) = 0;
            app.RL_2(length(app.RL_2)+1:lag) = 0;
            app.RL_3(length(app.RL_3)+1:lag) = 0;
            app.RR_1(length(app.RR_1)+1:lag) = 0;
            app.RR_2(length(app.RR_2)+1:lag) = 0;
            app.RR_3(length(app.RR_3)+1:lag) = 0;
        end
        
        function LockSettings(app)
            app.MasterSlider.Enable = 'off';
            app.ChannelSlider1.Enable = 'off';
            app.RearSpeaker1Slider.Enable = 'off';
            app.RearSpeaker2Slider.Enable = 'off';
            app.RearSpeaker3Slider.Enable = 'off';
            app.MuteButton1.Enable = 'off';
            app.MuteButton2.Enable = 'off';
            app.MuteButton3.Enable = 'off';
            app.MuteButton4.Enable = 'off';
            app.MuteButton5.Enable = 'off';
        end
        
        function UnlockSettings(app)
            app.MasterSlider.Enable = 'on';
            app.ChannelSlider1.Enable = 'on';
            app.RearSpeaker1Slider.Enable = 'on';
            app.RearSpeaker2Slider.Enable = 'on';
            app.RearSpeaker3Slider.Enable = 'on';
            app.MuteButton1.Enable = 'on';
            app.MuteButton2.Enable = 'on';
            app.MuteButton3.Enable = 'on';
            app.MuteButton4.Enable = 'on';
            app.MuteButton5.Enable = 'on';
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            imshow(app.Logo,'Parent',app.UIAxes);
            theta = 0:pi/50:2*pi;
            xunit = 0.5 * cos(theta) + 1;
            yunit = 0.5 * sin(theta) + 2;
            plot(app.FrontUIAxes, xunit, yunit, 'w', 'LineWidth', 2);
            plot(app.FrontUIAxes, [1 1], [2 2.5], 'w', 'LineWidth', 2);
        end

        % Button pushed function: LoadFileButton
        function LoadFileButtonPushed(app, event)
            while true
                [file,path] = uigetfile('*.wav');                    
                if isequal(file,0)
                    app.FileEditField.Value = 'Not valid file selected';
                    break;
                else
                    filename = fullfile(path,file);
                    app.FileEditField.Value = filename; 
                    app.AudioFile = audioread(filename);
                    channels = size(app.AudioFile);
                    if channels(2) == 2 
                        app.LoadFile = 1;
                        app.StereoLeft = app.AudioFile(:,1);
                        app.StereoRight = app.AudioFile(:,2);
                        Fpremix(app);
                        break;
                    end
                end
            end
        end

        % Value changed function: CentralSpeakerSwitch
        function CentralSpeakerSwitchValueChanged(app, event)
            value = app.CentralSpeakerSwitch.Value;
            if strcmpi('On',value)
                app.CentralSpeakerKnob.Enable = 'on';
                app.CentralSpeakerKnobLabel.Enable = 'on';
            else
                app.CentralSpeakerKnob.Enable = 'off';
                app.CentralSpeakerKnobLabel.Enable = 'off';
            end
            Fpremix(app);
        end

        % Value changed function: RightSpeakerKnob
        function RightSpeakerKnobValueChanged(app, event)
            value = app.RightSpeakerKnob.Value;
            app.LeftSpeakerKnob.Value = 10 - value;
            Fpremix(app);
        end

        % Value changed function: LeftSpeakerKnob
        function LeftSpeakerKnobValueChanged(app, event)
            value = app.LeftSpeakerKnob.Value;
            app.RightSpeakerKnob.Value = 10 - value;
            Fpremix(app);
        end

        % Value changed function: FrontAngleSlider
        function FrontAngleSliderValueChanged(app, event)
            cla(app.FrontUIAxes);
            value = round(app.FrontAngleSlider.Value);
            theta = 0:pi/50:2*pi;
            xunit = 0.5 * cos(theta) + 1;
            yunit = 0.5 * sin(theta) + 2;
            xleft = 0.5 * cos(deg2rad(90 - value)) + 1;
            yleft = 0.5 * sin(deg2rad(90 - value)) + 2;
            xright = 0.5 * cos(deg2rad(90 + value)) + 1;
            yright = 0.5 * sin(deg2rad(90 + value)) + 2;
            plot(app.FrontUIAxes, xunit, yunit, 'w', 'LineWidth', 2);
            plot(app.FrontUIAxes, [1 xleft], [2 yleft], 'w', 'LineWidth', 2);
            plot(app.FrontUIAxes, [1 xright], [2 yright], 'w', 'LineWidth', 2);
            % Angles for SADIE db
            angle_left = num2str(value);
            angle_right = num2str(360 - value);
            angle_left_file = strcat('D:\Mint Room\Resources\HRTF Database\D1_HRIR_WAV\44K_16bit\azi_', angle_left, ',0_ele_0,0.wav');
            angle_right_file = strcat('D:\Mint Room\Resources\HRTF Database\D1_HRIR_WAV\44K_16bit\azi_', angle_right, ',0_ele_0,0.wav');
            app.FrontAngle1 = audioread(angle_left_file);
            app.FrontAngle2 = audioread(angle_right_file);
            Fpremix(app);
        end

        % Value changed function: Rear1ActivateSwitch
        function Rear1ActivateSwitchValueChanged(app, event)
            value = app.Rear1ActivateSwitch.Value;
            if strcmpi('On',value)
                app.OnLamp_2.Color = [0.3725 0.9804 0.7961];
                app.Rear1DelayKnob.Enable = 'on';
                app.DelayKnobLabel.Enable = 'on';
                app.Rear1EditField.Enable = 'on';
                app.Rear1AngleSlider.Enable = 'on';
                app.AngleSliderLabel.Enable = 'on';
                app.Rear1Switch.Enable = 'on';
                
                theta = 0:pi/50:2*pi;
                xunit = 0.5 * cos(theta) + 1;
                yunit = 0.5 * sin(theta) + 2;
                angle = app.Rear1AngleSlider.Value;
                if app.Rear1Switch.Value == 'L'
                    xspeaker = 0.5 * cos(deg2rad(angle + 90)) + 1;
                    yspeaker = 0.5 * sin(deg2rad(angle + 90)) + 2;
                else
                    xspeaker = 0.5 * cos(deg2rad(90 - angle)) + 1;
                    yspeaker = 0.5 * sin(deg2rad(90 - angle)) + 2;
                end
                plot(app.Rear1UIAxes, xunit, yunit, 'w', 'LineWidth', 2);
                plot(app.Rear1UIAxes, [1 xspeaker], [2 yspeaker], 'w', 'LineWidth', 2);
                    
            else
                app.OnLamp_2.Color = [0.902 0.902 0.902];
                app.Rear1DelayKnob.Enable = 'off';
                app.DelayKnobLabel.Enable = 'off';
                app.Rear1EditField.Enable = 'off'; 
                app.Rear1AngleSlider.Enable = 'off';
                app.AngleSliderLabel.Enable = 'off';
                app.Rear1Switch.Enable = 'off';
                
                cla(app.Rear1UIAxes);
            end
            Rpremix1(app);
        end

        % Value changed function: Rear1DelayKnob
        function Rear1DelayKnobValueChanged(app, event)
            value = app.Rear1DelayKnob.Value;
            app.Rear1EditField.Value = value;
            Rpremix1(app);
        end

        % Value changed function: Rear1AngleSlider
        function Rear1AngleSliderValueChanged(app, event)
            value = round(app.Rear1AngleSlider.Value);
            cla(app.Rear1UIAxes);
            theta = 0:pi/50:2*pi;
            xunit = 0.5 * cos(theta) + 1;
            yunit = 0.5 * sin(theta) + 2;
            if app.Rear1Switch.Value == 'L'
                xspeaker = 0.5 * cos(deg2rad(90 + value)) + 1;
                yspeaker = 0.5 * sin(deg2rad(90 + value)) + 2;
                % Angles for SADIE db
                angle = num2str(value);
            else
                xspeaker = 0.5 * cos(deg2rad(90 - value)) + 1;
                yspeaker = 0.5 * sin(deg2rad(90 - value)) + 2;
                % Angles for SADIE db
                angle = num2str(360 - value);
            end
            plot(app.Rear1UIAxes, xunit, yunit, 'w', 'LineWidth', 2);
            plot(app.Rear1UIAxes, [1 xspeaker], [2 yspeaker], 'w', 'LineWidth', 2);
            angle_file = strcat('D:\Mint Room\Resources\HRTF Database\D1_HRIR_WAV\44K_16bit\azi_', angle, ',0_ele_0,0.wav');
            app.RearAngle1 = audioread(angle_file);
            Rpremix1(app);
        end

        % Value changed function: Rear1Switch
        function Rear1SwitchValueChanged(app, event)
            value = app.Rear1Switch.Value;
            cla(app.Rear1UIAxes);
            angle = round(app.Rear1AngleSlider.Value);
            theta = 0:pi/50:2*pi;
            xunit = 0.5 * cos(theta) + 1;
            yunit = 0.5 * sin(theta) + 2;
            if value == 'L'
                xspeaker = 0.5 * cos(deg2rad(90 + angle)) + 1;
                yspeaker = 0.5 * sin(deg2rad(90 + angle)) + 2;
                angle_file = strcat('D:\Mint Room\Resources\HRTF Database\D1_HRIR_WAV\44K_16bit\azi_', num2str(angle), ',0_ele_0,0.wav');
                app.RearAngle1 = audioread(angle_file);
            else
                xspeaker = 0.5 * cos(deg2rad(90 - angle)) + 1;
                yspeaker = 0.5 * sin(deg2rad(90 - angle)) + 2;
                angle_file = strcat('D:\Mint Room\Resources\HRTF Database\D1_HRIR_WAV\44K_16bit\azi_', num2str(360 - angle), ',0_ele_0,0.wav');
                app.RearAngle1 = audioread(angle_file);
            end
            plot(app.Rear1UIAxes, xunit, yunit, 'w', 'LineWidth', 2);
            plot(app.Rear1UIAxes, [1 xspeaker], [2 yspeaker], 'w', 'LineWidth', 2);
            Rpremix1(app);
        end

        % Value changed function: Rear2ActivateSwitch
        function Rear2ActivateSwitchValueChanged(app, event)
            value = app.Rear2ActivateSwitch.Value;
            if strcmpi('On',value)
                app.OnLamp_3.Color = [0.3725 0.9804 0.7961];
                app.Rear2DelayKnob.Enable = 'on';
                app.DelayKnob_2Label.Enable = 'on';
                app.Rear2EditField.Enable = 'on';
                app.Rear2AngleSlider.Enable = 'on';
                app.AngleSlider_2Label.Enable = 'on';
                app.Rear2Switch.Enable = 'on';
                
                theta = 0:pi/50:2*pi;
                xunit = 0.5 * cos(theta) + 1;
                yunit = 0.5 * sin(theta) + 2;
                angle = app.Rear2AngleSlider.Value;
                if app.Rear2Switch.Value == 'L'
                    xspeaker = 0.5 * cos(deg2rad(angle + 90)) + 1;
                    yspeaker = 0.5 * sin(deg2rad(angle + 90)) + 2;
                else
                    xspeaker = 0.5 * cos(deg2rad(90 - angle)) + 1;
                    yspeaker = 0.5 * sin(deg2rad(90 - angle)) + 2;
                end
                plot(app.Rear2UIAxes, xunit, yunit, 'w', 'LineWidth', 2);
                plot(app.Rear2UIAxes, [1 xspeaker], [2 yspeaker], 'w', 'LineWidth', 2);
                    
            else
                app.OnLamp_3.Color = [0.902 0.902 0.902];
                app.Rear2DelayKnob.Enable = 'off';
                app.DelayKnob_2Label.Enable = 'off';
                app.Rear2EditField.Enable = 'off'; 
                app.Rear2AngleSlider.Enable = 'off';
                app.AngleSlider_2Label.Enable = 'off';
                app.Rear2Switch.Enable = 'off';
                
                cla(app.Rear2UIAxes);
            end
            Rpremix2(app);
        end

        % Value changed function: Rear2DelayKnob
        function Rear2DelayKnobValueChanged(app, event)
            value = app.Rear2DelayKnob.Value;
            app.Rear2EditField.Value = value;
            Rpremix2(app);
        end

        % Value changed function: Rear2AngleSlider
        function Rear2AngleSliderValueChanged(app, event)
            value = round(app.Rear2AngleSlider.Value);
            cla(app.Rear2UIAxes);
            theta = 0:pi/50:2*pi;
            xunit = 0.5 * cos(theta) + 1;
            yunit = 0.5 * sin(theta) + 2;
            if app.Rear2Switch.Value == 'L'
                xspeaker = 0.5 * cos(deg2rad(90 + value)) + 1;
                yspeaker = 0.5 * sin(deg2rad(90 + value)) + 2;
                % Angles for SADIE db
                angle = num2str(value);
            else
                xspeaker = 0.5 * cos(deg2rad(90 - value)) + 1;
                yspeaker = 0.5 * sin(deg2rad(90 - value)) + 2;
                % Angles for SADIE db
                angle = num2str(360 - value);
            end
            plot(app.Rear2UIAxes, xunit, yunit, 'w', 'LineWidth', 2);
            plot(app.Rear2UIAxes, [1 xspeaker], [2 yspeaker], 'w', 'LineWidth', 2);
            angle_file = strcat('D:\Mint Room\Resources\HRTF Database\D1_HRIR_WAV\44K_16bit\azi_', angle, ',0_ele_0,0.wav');
            app.RearAngle1 = audioread(angle_file);
            Rpremix2(app);
        end

        % Value changed function: Rear2Switch
        function Rear2SwitchValueChanged(app, event)
            value = app.Rear2Switch.Value;
            cla(app.Rear2UIAxes);
            angle = round(app.Rear2AngleSlider.Value);
            theta = 0:pi/50:2*pi;
            xunit = 0.5 * cos(theta) + 1;
            yunit = 0.5 * sin(theta) + 2;
            if value == 'L'
                xspeaker = 0.5 * cos(deg2rad(90 + angle)) + 1;
                yspeaker = 0.5 * sin(deg2rad(90 + angle)) + 2;
                angle_file = strcat('D:\Mint Room\Resources\HRTF Database\D1_HRIR_WAV\44K_16bit\azi_', num2str(angle), ',0_ele_0,0.wav');
                app.RearAngle2 = audioread(angle_file);
            else
                xspeaker = 0.5 * cos(deg2rad(90 - angle)) + 1;
                yspeaker = 0.5 * sin(deg2rad(90 - angle)) + 2;
                angle_file = strcat('D:\Mint Room\Resources\HRTF Database\D1_HRIR_WAV\44K_16bit\azi_', num2str(360 - angle), ',0_ele_0,0.wav');
                app.RearAngle2 = audioread(angle_file);
            end
            plot(app.Rear2UIAxes, xunit, yunit, 'w', 'LineWidth', 2);
            plot(app.Rear2UIAxes, [1 xspeaker], [2 yspeaker], 'w', 'LineWidth', 2);
            Rpremix2(app);
        end

        % Value changed function: Rear3ActivateSwitch
        function Rear3ActivateSwitchValueChanged(app, event)
            value = app.Rear3ActivateSwitch.Value;
            if strcmpi('On',value)
                app.OnLamp_4.Color = [0.3725 0.9804 0.7961];
                app.Rear3DelayKnob.Enable = 'on';
                app.DelayKnob_3Label.Enable = 'on';
                app.Rear3EditField.Enable = 'on';
                app.Rear3AngleSlider.Enable = 'on';
                app.AngleSlider_3Label.Enable = 'on';
                app.Rear3Switch.Enable = 'on';
                
                theta = 0:pi/50:2*pi;
                xunit = 0.5 * cos(theta) + 1;
                yunit = 0.5 * sin(theta) + 2;
                angle = app.Rear3AngleSlider.Value;
                if app.Rear3Switch.Value == 'L'
                    xspeaker = 0.5 * cos(deg2rad(angle + 90)) + 1;
                    yspeaker = 0.5 * sin(deg2rad(angle + 90)) + 2;
                else
                    xspeaker = 0.5 * cos(deg2rad(90 - angle)) + 1;
                    yspeaker = 0.5 * sin(deg2rad(90 - angle)) + 2;
                end
                plot(app.Rear3UIAxes, xunit, yunit, 'w', 'LineWidth', 2);
                plot(app.Rear3UIAxes, [1 xspeaker], [2 yspeaker], 'w', 'LineWidth', 2);
                    
            else
                app.OnLamp_4.Color = [0.902 0.902 0.902];
                app.Rear3DelayKnob.Enable = 'off';
                app.DelayKnob_3Label.Enable = 'off';
                app.Rear3EditField.Enable = 'off'; 
                app.Rear3AngleSlider.Enable = 'off';
                app.AngleSlider_3Label.Enable = 'off';
                app.Rear3Switch.Enable = 'off';
                
                cla(app.Rear3UIAxes);
            end
            Rpremix3(app);
        end

        % Value changed function: Rear3DelayKnob
        function Rear3DelayKnobValueChanged(app, event)
            value = app.Rear3DelayKnob.Value;
            app.Rear3EditField.Value = value;
            Rpremix3(app);
        end

        % Value changed function: Rear3AngleSlider
        function Rear3AngleSliderValueChanged(app, event)
            value = round(app.Rear3AngleSlider.Value);
            cla(app.Rear3UIAxes);
            theta = 0:pi/50:2*pi;
            xunit = 0.5 * cos(theta) + 1;
            yunit = 0.5 * sin(theta) + 2;
            if app.Rear3Switch.Value == 'L'
                xspeaker = 0.5 * cos(deg2rad(90 + value)) + 1;
                yspeaker = 0.5 * sin(deg2rad(90 + value)) + 2;
                % Angle for SADIE db
                angle = num2str(value);
            else
                xspeaker = 0.5 * cos(deg2rad(90 - value)) + 1;
                yspeaker = 0.5 * sin(deg2rad(90 - value)) + 2;
                % Angle for SADIE db
                angle = num2str(360 - value);
            end
            plot(app.Rear3UIAxes, xunit, yunit, 'w', 'LineWidth', 2);
            plot(app.Rear3UIAxes, [1 xspeaker], [2 yspeaker], 'w', 'LineWidth', 2);
            angle_file = strcat('D:\Mint Room\Resources\HRTF Database\D1_HRIR_WAV\44K_16bit\azi_', angle, ',0_ele_0,0.wav');
            app.RearAngle1 = audioread(angle_file);
            Rpremix3(app);
        end

        % Value changed function: Rear3Switch
        function Rear3SwitchValueChanged(app, event)
            value = app.Rear3Switch.Value;
            cla(app.Rear3UIAxes);
            angle = round(app.Rear3AngleSlider.Value);
            theta = 0:pi/50:2*pi;
            xunit = 0.5 * cos(theta) + 1;
            yunit = 0.5 * sin(theta) + 2;
            if value == 'L'
                xspeaker = 0.5 * cos(deg2rad(90 + angle)) + 1;
                yspeaker = 0.5 * sin(deg2rad(90 + angle)) + 2;
                angle_file = strcat('D:\Mint Room\Resources\HRTF Database\D1_HRIR_WAV\44K_16bit\azi_', num2str(angle), ',0_ele_0,0.wav');
                app.RearAngle3 = audioread(angle_file);
            else
                xspeaker = 0.5 * cos(deg2rad(90 - angle)) + 1;
                yspeaker = 0.5 * sin(deg2rad(90 - angle)) + 2;
                angle_file = strcat('D:\Mint Room\Resources\HRTF Database\D1_HRIR_WAV\44K_16bit\azi_', num2str(360 - angle), ',0_ele_0,0.wav');
                app.RearAngle3 = audioread(angle_file);
            end
            plot(app.Rear3UIAxes, xunit, yunit, 'w', 'LineWidth', 2);
            plot(app.Rear3UIAxes, [1 xspeaker], [2 yspeaker], 'w', 'LineWidth', 2);
            Rpremix3(app);
        end

        % Button pushed function: PlayButton
        function PlayButtonPushed(app, event)
            Size_Modification(app)
            L = app.FL + app.RL_1 + app.RL_2 + app.RL_3;
            R = app.FR + app.RR_1 + app.RR_2 + app.RR_3;
            app.Output = zeros(length(L),2);
            app.Output(:,1) = L;
            app.Output(:,2) = R;
            app.Play = 1;
            LockSettings(app)
            app.myplayer = audioplayer(app.MasterSlider.Value/5 * app.Output, 44100);
            start = 1;
            stop = app.myplayer.SampleRate * 35;
            play(app.myplayer, [start, stop]);
            pause(35);
            UnlockSettings(app)
            app.Play = 0;
        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            stop(app.myplayer);
            UnlockSettings(app)
            app.Play = 0;
        end

        % Value changed function: ChannelSlider1
        function ChannelSlider1ValueChanged(app, event)
            %value = app.ChannelSlider1.Value;
            Fpremix(app);
            if app.Play == 1
                app.PlayButtonPushed(app);
            end
        end

        % Value changed function: RearSpeaker1Slider
        function RearSpeaker1SliderValueChanged(app, event)
            Rpremix1(app);
            if all(app.Play == 1 & app.OnLamp_3.Color == [0.3725 0.9804 0.7961])
                app.PlayButtonPushed(app);
            end
        end

        % Value changed function: RearSpeaker2Slider
        function RearSpeaker2SliderValueChanged(app, event)
            Rpremix2(app);
            if all(app.Play == 1 & app.OnLamp_3.Color == [0.3725 0.9804 0.7961])
                app.PlayButtonPushed(app);
            end
        end

        % Value changed function: RearSpeaker3Slider
        function RearSpeaker3SliderValueChanged(app, event)
            Rpremix3(app);  
            if all(app.Play == 1 & app.OnLamp_4.Color == [0.3725 0.9804 0.7961])
                app.PlayButtonPushed(app);
            end
        end

        % Value changed function: MasterSlider
        function MasterSliderValueChanged(app, event)
            if (app.LoadFile == 1 && app.Play == 1) 
                value = app.MasterSlider.Value;
                NewStart = get(app.myplayer,'CurrentSample') + 1;
                stop(app.myplayer);
                app.Output = app.Output(NewStart:end,:);
                app.myplayer = audioplayer(value/5 * app.Output,44100);
                play(app.myplayer);
            end
        end

        % Button pushed function: SaveSettingsButton
        function SaveSettingsButtonPushed(app, event)
            startingFolder = uigetdir;
            defaultFileName = fullfile(startingFolder, '*.txt');
            [baseFileName, folder] = uiputfile(defaultFileName, 'Specify a file');
            if baseFileName == 0
              % User clicked the Cancel button.
              return;
            end
            fullFileName = fullfile(folder, baseFileName);
            fid = fopen(fullFileName, 'wt');
            if fid ~= -1
                fprintf(fid, '%s\n', app.CentralSpeakerSwitch.Value);
                fprintf(fid, '%f\n', app.LeftSpeakerKnob.Value);
                if strcmpi('On',app.CentralSpeakerSwitch.Value)
                    fprintf(fid, '%f\n', app.CentralSpeakerKnob.Value);
                end
                fprintf(fid, '%f\n', app.RightSpeakerKnob.Value);
                fprintf(fid, '%d\n', round(app.FrontAngleSlider.Value));
                fprintf(fid, '%s\n', app.Rear1ActivateSwitch.Value);
                if strcmpi('On',app.Rear1ActivateSwitch.Value)
                    fprintf(fid, '%f\n', app.Rear1DelayKnob.Value);
                    fprintf(fid, '%d\n', round(app.Rear1AngleSlider.Value));
                    fprintf(fid, '%s\n', app.Rear1Switch.Value);
                end
                fprintf(fid, '%s\n', app.Rear2ActivateSwitch.Value);
                if strcmpi('On',app.Rear2ActivateSwitch.Value)
                    fprintf(fid, '%f\n', app.Rear2DelayKnob.Value);
                    fprintf(fid, '%d\n', round(app.Rear2AngleSlider.Value));
                    fprintf(fid, '%s\n', app.Rear2Switch.Value);
                end
                fprintf(fid, '%s\n', app.Rear3ActivateSwitch.Value);
                if strcmpi('On',app.Rear3ActivateSwitch.Value)
                    fprintf(fid, '%f\n', app.Rear3DelayKnob.Value);
                    fprintf(fid, '%d\n', round(app.Rear3AngleSlider.Value));
                    fprintf(fid, '%s\n', app.Rear3Switch.Value);
                end
            else
                warningMessage = sprintf('Cannot open file:\n%s', fullFileName);
                uiwait(warndlg(warningMessage));
            end
        end

        % Button pushed function: HelpButton
        function HelpButtonPushed(app, event)
            app.HelpPanel.Visible = 'on';
        end

        % Button pushed function: GotitButton
        function GotitButtonPushed(app, event)
            app.HelpPanel.Visible = 'off';
        end

        % Button pushed function: SaveFileButton
        function SaveFileButtonPushed(app, event)
            startfolder = uigetdir;
            defaultfilename = fullfile(startfolder, '*.wav');
            [baseFileName, folder] = uiputfile(defaultfilename, 'Specify a file');
            if baseFileName == 0
              % User clicked the Cancel button.
              return;
            end
            fullfilename = fullfile(folder, baseFileName);
            audiowrite(fullfilename, app.Output, 44100);
        end

        % Value changed function: MuteButton1
        function MuteButton1ValueChanged(app, event)
            value = app.MuteButton1.Value;
            if value == 1
                app.ChannelSlider1.Value = 0;
                Fpremix(app);
                if app.Play == 1
                    app.PlayButtonPushed(app);
                end
            else
                app.ChannelSlider1.Value = 5;
                Fpremix(app);
                if app.Play == 1
                    app.PlayButtonPushed(app);
                end
            end
        end

        % Value changed function: MuteButton2
        function MuteButton2ValueChanged(app, event)
            value = app.MuteButton2.Value;
            if value == 1
                app.RearSpeaker1Slider.Value = 0;
                Rpremix1(app);
                if app.Play == 1
                    app.PlayButtonPushed(app);
                end
            else
                app.RearSpeaker1Slider.Value = 5;
                Rpremix1(app);
                if app.Play == 1
                    app.PlayButtonPushed(app);
                end
            end
        end

        % Value changed function: MuteButton3
        function MuteButton3ValueChanged(app, event)
            value = app.MuteButton3.Value;
            if value == 1
                app.RearSpeaker2Slider.Value = 0;
                Rpremix2(app);
                if app.Play == 1
                    app.PlayButtonPushed(app);
                end
            else
                app.RearSpeaker2Slider.Value = 5;
                Rpremix2(app);
                if app.Play == 1
                    app.PlayButtonPushed(app);
                end
            end
        end

        % Value changed function: MuteButton4
        function MuteButton4ValueChanged(app, event)
            value = app.MuteButton4.Value;
            if value == 1
                app.RearSpeaker3Slider.Value = 0;
                Rpremix3(app);
                if app.Play == 1
                    app.PlayButtonPushed(app);
                end
            else
                app.RearSpeaker3Slider.Value = 5;
                Rpremix3(app);
                if app.Play == 1
                    app.PlayButtonPushed(app);
                end
            end
        end

        % Value changed function: MuteButton5
        function MuteButton5ValueChanged(app, event)
            value = app.MuteButton5.Value;
            if value == 1
                app.MasterSlider.Value = 0;
                MasterSliderValueChanged(app);
            else
                app.MasterSlider.Value = 5;
                MasterSliderValueChanged(app);
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.MintRoomUIFigure = uifigure('Visible', 'off');
            app.MintRoomUIFigure.Name = 'Mint Room';
            app.MintRoomLabel = uilabel(app.MintRoomUIFigure);
            app.MintRoomLabel.Text = 'Mint Room';
            app.SettingsPanel = uipanel(app.MintRoomUIFigure);
            app.SettingsPanel.Title = 'Settings';
            app.TabGroup = uitabgroup(app.SettingsPanel);
            app.FrontSpeakersTab = uitab(app.TabGroup);
            app.FrontSpeakersTab.Title = 'Front Speakers';
            app.FrontParametersPanel = uipanel(app.FrontSpeakersTab);
            app.FrontParametersPanel.Title = 'Parameters';
            app.GainLevelsPanel = uipanel(app.FrontParametersPanel);
            app.GainLevelsPanel.Title = 'Gain Levels';
            app.RightSpeakerKnobLabel = uilabel(app.GainLevelsPanel);
            app.RightSpeakerKnobLabel.Text = 'Right Speaker';
            app.RightSpeakerKnob = uiknob(app.GainLevelsPanel, 'continuous');
            app.RightSpeakerKnob.Limits = [0 10];
            app.RightSpeakerKnob.MajorTicks = [0 2 4 6 8 10];
            app.RightSpeakerKnob.ValueChangedFcn = createCallbackFcn(app, @RightSpeakerKnobValueChanged, true);
            app.RightSpeakerKnob.Value = 5;
            app.LeftSpeakerKnob_2Label = uilabel(app.GainLevelsPanel);
            app.LeftSpeakerKnob_2Label.Text = 'Left Speaker';
            app.LeftSpeakerKnob = uiknob(app.GainLevelsPanel, 'continuous');
            app.LeftSpeakerKnob.Limits = [0 10];
            app.LeftSpeakerKnob.MajorTicks = [0 2 4 6 8 10];
            app.LeftSpeakerKnob.ValueChangedFcn = createCallbackFcn(app, @LeftSpeakerKnobValueChanged, true);
            app.LeftSpeakerKnob.Value = 5;
            app.CentralSpeakerKnobLabel = uilabel(app.GainLevelsPanel);
            app.CentralSpeakerKnobLabel.Enable = 'off';
            app.CentralSpeakerKnobLabel.Text = 'Central Speaker';
            app.CentralSpeakerKnob = uiknob(app.GainLevelsPanel, 'continuous');
            app.CentralSpeakerKnob.Limits = [0 10];
            app.CentralSpeakerKnob.MajorTicks = [0 2 4 6 8 10];
            app.CentralSpeakerKnob.Enable = 'off';
            app.CentralSpeakerKnob.Value = 5;
            app.CentralActivationPanel = uipanel(app.FrontParametersPanel);
            app.CentralSpeakerSwitchLabel = uilabel(app.CentralActivationPanel);
            app.CentralSpeakerSwitchLabel.Text = 'Central Speaker';
            app.CentralSpeakerSwitch = uiswitch(app.CentralActivationPanel, 'slider');
            app.CentralSpeakerSwitch.ValueChangedFcn = createCallbackFcn(app, @CentralSpeakerSwitchValueChanged, true);
            app.FrontAnglePanel = uipanel(app.FrontParametersPanel);
            app.AngleSlider_9Label = uilabel(app.FrontAnglePanel);
            app.AngleSlider_9Label.Text = 'Angle';
            app.FrontAngleSlider = uislider(app.FrontAnglePanel);
            app.FrontAngleSlider.Limits = [0 90];
            app.FrontAngleSlider.ValueChangedFcn = createCallbackFcn(app, @FrontAngleSliderValueChanged, true);
            app.FrontMonitorPanel = uipanel(app.FrontParametersPanel);
            app.FrontMonitorPanel.Title = 'Monitor';
            app.FrontUIAxes = uiaxes(app.FrontMonitorPanel);
            app.FrontUIAxes.XTick = [];
            app.FrontUIAxes.YTick = [];
            app.FrontUIAxes.Visible = 'off';
            app.RearSpeaker1Tab = uitab(app.TabGroup);
            app.RearSpeaker1Tab.Title = 'Rear Speaker 1';
            app.Rear1ParametersPanel = uipanel(app.RearSpeaker1Tab);
            app.Rear1ParametersPanel.Title = 'Parameters';
            app.Rear1Panel1 = uipanel(app.Rear1ParametersPanel);
            app.ActivateSwitchLabel = uilabel(app.Rear1Panel1);
            app.ActivateSwitchLabel.Text = 'Activate';
            app.Rear1ActivateSwitch = uiswitch(app.Rear1Panel1, 'slider');
            app.Rear1ActivateSwitch.ValueChangedFcn = createCallbackFcn(app, @Rear1ActivateSwitchValueChanged, true);
            app.Rear1Panel2 = uipanel(app.Rear1ParametersPanel);
            app.DelayKnobLabel = uilabel(app.Rear1Panel2);
            app.DelayKnobLabel.Enable = 'off';
            app.DelayKnobLabel.Text = 'Delay';
            app.Rear1DelayKnob = uiknob(app.Rear1Panel2, 'continuous');
            app.Rear1DelayKnob.Limits = [0 10];
            app.Rear1DelayKnob.MajorTicks = [0 2 4 6 8 10];
            app.Rear1DelayKnob.ValueChangedFcn = createCallbackFcn(app, @Rear1DelayKnobValueChanged, true);
            app.Rear1DelayKnob.Enable = 'off';
            app.Rear1EditField = uieditfield(app.Rear1Panel2, 'numeric');
            app.Rear1EditField.Enable = 'off';
            app.Rear1Panel3 = uipanel(app.Rear1ParametersPanel);
            app.AngleSliderLabel = uilabel(app.Rear1Panel3);
            app.AngleSliderLabel.Enable = 'off';
            app.AngleSliderLabel.Text = 'Angle';
            app.Rear1AngleSlider = uislider(app.Rear1Panel3);
            app.Rear1AngleSlider.Limits = [90 180];
            app.Rear1AngleSlider.ValueChangedFcn = createCallbackFcn(app, @Rear1AngleSliderValueChanged, true);
            app.Rear1AngleSlider.Enable = 'off';
            app.Rear1AngleSlider.Value = 90;
            app.Rear1Switch = uiswitch(app.Rear1Panel3, 'slider');
            app.Rear1Switch.Items = {'L', 'R'};
            app.Rear1Switch.ValueChangedFcn = createCallbackFcn(app, @Rear1SwitchValueChanged, true);
            app.Rear1Switch.Enable = 'off';
            app.Rear1Switch.Value = 'L';
            app.Rear1MonitorPanel = uipanel(app.Rear1ParametersPanel);
            app.Rear1MonitorPanel.Title = 'Monitor';
            app.Rear1UIAxes = uiaxes(app.Rear1MonitorPanel);
            app.Rear1UIAxes.XTick = [];
            app.Rear1UIAxes.YTick = [];
            app.Rear1UIAxes.Visible = 'off';
            app.RearSpeaker2Tab = uitab(app.TabGroup);
            app.RearSpeaker2Tab.Title = 'Rear Speaker 2';
            app.Rear2ParametersPanel = uipanel(app.RearSpeaker2Tab);
            app.Rear2ParametersPanel.Title = 'Parameters';
            app.Rear2Panel1 = uipanel(app.Rear2ParametersPanel);
            app.ActivateSwitch_2Label = uilabel(app.Rear2Panel1);
            app.ActivateSwitch_2Label.Text = 'Activate';
            app.Rear2ActivateSwitch = uiswitch(app.Rear2Panel1, 'slider');
            app.Rear2ActivateSwitch.ValueChangedFcn = createCallbackFcn(app, @Rear2ActivateSwitchValueChanged, true);
            app.Rear2Panel2 = uipanel(app.Rear2ParametersPanel);
            app.DelayKnob_2Label = uilabel(app.Rear2Panel2);
            app.DelayKnob_2Label.Enable = 'off';
            app.DelayKnob_2Label.Text = 'Delay';
            app.Rear2DelayKnob = uiknob(app.Rear2Panel2, 'continuous');
            app.Rear2DelayKnob.Limits = [0 10];
            app.Rear2DelayKnob.MajorTicks = [0 2 4 6 8 10];
            app.Rear2DelayKnob.ValueChangedFcn = createCallbackFcn(app, @Rear2DelayKnobValueChanged, true);
            app.Rear2DelayKnob.Enable = 'off';
            app.Rear2EditField = uieditfield(app.Rear2Panel2, 'numeric');
            app.Rear2EditField.Enable = 'off';
            app.Rear2Panel3 = uipanel(app.Rear2ParametersPanel);
            app.AngleSlider_2Label = uilabel(app.Rear2Panel3);
            app.AngleSlider_2Label.Enable = 'off';
            app.AngleSlider_2Label.Text = 'Angle';
            app.Rear2AngleSlider = uislider(app.Rear2Panel3);
            app.Rear2AngleSlider.Limits = [90 180];
            app.Rear2AngleSlider.ValueChangedFcn = createCallbackFcn(app, @Rear2AngleSliderValueChanged, true);
            app.Rear2AngleSlider.Enable = 'off';
            app.Rear2AngleSlider.Value = 90;
            app.Rear2Switch = uiswitch(app.Rear2Panel3, 'slider');
            app.Rear2Switch.Items = {'L', 'R'};
            app.Rear2Switch.ValueChangedFcn = createCallbackFcn(app, @Rear2SwitchValueChanged, true);
            app.Rear2Switch.Enable = 'off';
            app.Rear2Switch.Value = 'L';
            app.Rear2MonitorPanel = uipanel(app.Rear2ParametersPanel);
            app.Rear2MonitorPanel.Title = 'Monitor';
            app.Rear2UIAxes = uiaxes(app.Rear2MonitorPanel);
            app.Rear2UIAxes.XTick = [];
            app.Rear2UIAxes.YTick = [];
            app.Rear2UIAxes.Visible = 'off';
            app.RearSpeaker3Tab = uitab(app.TabGroup);
            app.RearSpeaker3Tab.Title = 'Rear Speaker 3';
            app.Rear3ParametersPanel = uipanel(app.RearSpeaker3Tab);
            app.Rear3ParametersPanel.Title = 'Parameters';
            app.Rear3Panel1 = uipanel(app.Rear3ParametersPanel);
            app.ActivateSwitch_3Label = uilabel(app.Rear3Panel1);
            app.ActivateSwitch_3Label.Text = 'Activate';
            app.Rear3ActivateSwitch = uiswitch(app.Rear3Panel1, 'slider');
            app.Rear3ActivateSwitch.ValueChangedFcn = createCallbackFcn(app, @Rear3ActivateSwitchValueChanged, true);
            app.Rear3Panel2 = uipanel(app.Rear3ParametersPanel);
            app.DelayKnob_3Label = uilabel(app.Rear3Panel2);
            app.DelayKnob_3Label.Enable = 'off';
            app.DelayKnob_3Label.Text = 'Delay';
            app.Rear3DelayKnob = uiknob(app.Rear3Panel2, 'continuous');
            app.Rear3DelayKnob.Limits = [0 10];
            app.Rear3DelayKnob.MajorTicks = [0 2 4 6 8 10];
            app.Rear3DelayKnob.ValueChangedFcn = createCallbackFcn(app, @Rear3DelayKnobValueChanged, true);
            app.Rear3DelayKnob.Enable = 'off';
            app.Rear3EditField = uieditfield(app.Rear3Panel2, 'numeric');
            app.Rear3EditField.Enable = 'off';
            app.Rear3Panel3 = uipanel(app.Rear3ParametersPanel);
            app.AngleSlider_3Label = uilabel(app.Rear3Panel3);
            app.AngleSlider_3Label.Enable = 'off';
            app.AngleSlider_3Label.Text = 'Angle';
            app.Rear3AngleSlider = uislider(app.Rear3Panel3);
            app.Rear3AngleSlider.Limits = [90 180];
            app.Rear3AngleSlider.ValueChangedFcn = createCallbackFcn(app, @Rear3AngleSliderValueChanged, true);
            app.Rear3AngleSlider.Enable = 'off';
            app.Rear3AngleSlider.Value = 90;
            app.Rear3Switch = uiswitch(app.Rear3Panel3, 'slider');
            app.Rear3Switch.Items = {'L', 'R'};
            app.Rear3Switch.ValueChangedFcn = createCallbackFcn(app, @Rear3SwitchValueChanged, true);
            app.Rear3Switch.Enable = 'off';
            app.Rear3Switch.Value = 'L';
            app.Rear3MonitorPanel = uipanel(app.Rear3ParametersPanel);
            app.Rear3MonitorPanel.Title = 'Monitor';
            app.Rear3UIAxes = uiaxes(app.Rear3MonitorPanel);
            app.Rear3UIAxes.XTick = [];
            app.Rear3UIAxes.YTick = [];
            app.Rear3UIAxes.Visible = 'off';
            app.BinauralMixerPanel = uipanel(app.MintRoomUIFigure);
            app.BinauralMixerPanel.Title = 'Binaural Mixer';
            app.ChannelPanel1 = uipanel(app.BinauralMixerPanel);
            app.FrontSpeakersSliderLabel = uilabel(app.ChannelPanel1);
            app.FrontSpeakersSliderLabel.Text = 'Front Speakers';
            app.ChannelSlider1 = uislider(app.ChannelPanel1);
            app.ChannelSlider1.Limits = [0 10];
            app.ChannelSlider1.ValueChangedFcn = createCallbackFcn(app, @ChannelSlider1ValueChanged, true);
            app.ChannelSlider1.Value = 5;
            app.Lamp1 = uilamp(app.ChannelPanel1);
            app.MuteButton1 = uibutton(app.ChannelPanel1, 'state');
            app.MuteButton1.ValueChangedFcn = createCallbackFcn(app, @MuteButton1ValueChanged, true);
            app.MuteButton1.Icon = 'mute.png';
            app.MuteButton1.Text = '';
            app.Panel_2 = uipanel(app.BinauralMixerPanel);
            app.RearSpeaker1SliderLabel = uilabel(app.Panel_2);
            app.RearSpeaker1SliderLabel.Text = 'Rear Speaker 1';
            app.RearSpeaker1Slider = uislider(app.Panel_2);
            app.RearSpeaker1Slider.Limits = [0 10];
            app.RearSpeaker1Slider.ValueChangedFcn = createCallbackFcn(app, @RearSpeaker1SliderValueChanged, true);
            app.RearSpeaker1Slider.Value = 5;
            app.MuteButton2 = uibutton(app.Panel_2, 'state');
            app.MuteButton2.ValueChangedFcn = createCallbackFcn(app, @MuteButton2ValueChanged, true);
            app.MuteButton2.Icon = 'mute.png';
            app.MuteButton2.Text = '';
            app.OnLamp_2 = uilamp(app.Panel_2);
            app.Panel_3 = uipanel(app.BinauralMixerPanel);
            app.RearSpeaker2SliderLabel = uilabel(app.Panel_3);
            app.RearSpeaker2SliderLabel.Text = 'Rear Speaker 2';
            app.RearSpeaker2Slider = uislider(app.Panel_3);
            app.RearSpeaker2Slider.Limits = [0 10];
            app.RearSpeaker2Slider.ValueChangedFcn = createCallbackFcn(app, @RearSpeaker2SliderValueChanged, true);
            app.RearSpeaker2Slider.Value = 5;
            app.OnLamp_3 = uilamp(app.Panel_3);
            app.MuteButton3 = uibutton(app.Panel_3, 'state');
            app.MuteButton3.ValueChangedFcn = createCallbackFcn(app, @MuteButton3ValueChanged, true);
            app.MuteButton3.Icon = 'mute.png';
            app.MuteButton3.Text = '';
            app.Panel_4 = uipanel(app.BinauralMixerPanel);
            app.RearSpeaker3SliderLabel = uilabel(app.Panel_4);
            app.RearSpeaker3SliderLabel.Text = 'Rear Speaker 3';
            app.RearSpeaker3Slider = uislider(app.Panel_4);
            app.RearSpeaker3Slider.Limits = [0 10];
            app.RearSpeaker3Slider.ValueChangedFcn = createCallbackFcn(app, @RearSpeaker3SliderValueChanged, true);
            app.RearSpeaker3Slider.Value = 5;
            app.OnLamp_4 = uilamp(app.Panel_4);
            app.MuteButton4 = uibutton(app.Panel_4, 'state');
            app.MuteButton4.ValueChangedFcn = createCallbackFcn(app, @MuteButton4ValueChanged, true);
            app.MuteButton4.Icon = 'mute.png';
            app.MuteButton4.Text = '';
            app.Panel_5 = uipanel(app.BinauralMixerPanel);
            app.MasterSliderLabel = uilabel(app.Panel_5);
            app.MasterSliderLabel.Text = 'Master';
            app.MasterSlider = uislider(app.Panel_5);
            app.MasterSlider.Limits = [0 10];
            app.MasterSlider.ValueChangedFcn = createCallbackFcn(app, @MasterSliderValueChanged, true);
            app.MasterSlider.Value = 5;
            app.OnLamp_5 = uilamp(app.Panel_5);
            app.MuteButton5 = uibutton(app.Panel_5, 'state');
            app.MuteButton5.ValueChangedFcn = createCallbackFcn(app, @MuteButton5ValueChanged, true);
            app.MuteButton5.Icon = 'mute.png';
            app.MuteButton5.Text = '';
            app.FileControlPanel = uipanel(app.MintRoomUIFigure);
            app.FileControlPanel.Title = 'File Control';
            app.PlayButton = uibutton(app.FileControlPanel, 'push');
            app.PlayButton.ButtonPushedFcn = createCallbackFcn(app, @PlayButtonPushed, true);
            app.PlayButton.Icon = 'play.png';
            app.PlayButton.Text = '';
            app.StopButton = uibutton(app.FileControlPanel, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Icon = 'stop.png';
            app.StopButton.Text = '';
            app.HelpButton = uibutton(app.FileControlPanel, 'push');
            app.HelpButton.ButtonPushedFcn = createCallbackFcn(app, @HelpButtonPushed, true);
            app.HelpButton.Text = '?';
            app.SaveFileButton = uibutton(app.FileControlPanel, 'push');
            app.SaveFileButton.ButtonPushedFcn = createCallbackFcn(app, @SaveFileButtonPushed, true);
            app.SaveFileButton.Icon = 'save_file.png';
            app.SaveFileButton.Text = '';
            app.SaveSettingsButton = uibutton(app.FileControlPanel, 'push');
            app.SaveSettingsButton.ButtonPushedFcn = createCallbackFcn(app, @SaveSettingsButtonPushed, true);
            app.SaveSettingsButton.Icon = 'save_settings.png';
            app.SaveSettingsButton.Text = '';
            app.LoadFileButton = uibutton(app.FileControlPanel, 'push');
            app.LoadFileButton.ButtonPushedFcn = createCallbackFcn(app, @LoadFileButtonPushed, true);
            app.LoadFileButton.Icon = 'load_file.png';
            app.LoadFileButton.Text = '';
            app.FileLabel = uilabel(app.FileControlPanel);
            app.FileLabel.Text = 'File:';
            app.FileEditField = uieditfield(app.FileControlPanel, 'text');
            app.FileEditField.Value = 'No file selected';
            app.BinauralUpmixerApplicationLabel = uilabel(app.MintRoomUIFigure);
            app.BinauralUpmixerApplicationLabel.Text = 'Binaural Upmixer Application';
            app.LamprosChantzisAudiogroupofPatrasUniversityLabel = uilabel(app.MintRoomUIFigure);
            app.LamprosChantzisAudiogroupofPatrasUniversityLabel.Text = '© Lampros Chantzis & Audiogroup of Patras University';
            app.UIAxes = uiaxes(app.MintRoomUIFigure);
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.UIAxes.Visible = 'off';
            app.HelpPanel = uipanel(app.MintRoomUIFigure);
            app.HelpPanel.Title = 'Help';
            app.HelpPanel.Visible = 'off';
            app.TextArea = uitextarea(app.HelpPanel);
            app.TextArea.Value = {'HOW TO USE'; '--------------------------------------------------------------------------------------------------------'; 'i) Import a file:'; 'Click on the first button on File Control menu to load a wav file from your computer. The file''s name will be displayed in the'; 'bar below.'; ''; 'ii) Import previous settings:'; 'Click on the second button on File Control menu to load a txt file that contains settings that were previously saved by user.'; 'As soon as the file is loaded'; ' settings will automatically be applied.'; ''; 'iii) Edit front speakers:'; 'On the Settings panel'; ' on the tab "Front Speakers" you can modify the parameters for the front pair of speakers. You can check'; 'if you want to enable central speaker too. Note that on the monitor section you can see the angles you have set for the speakers.'; 'The front speakers have not the add-delay feature.'; ''; 'iv) Edit rear speakers:'; 'On the left of the Settings panel by clicking the different tabs you can move to different speakers parameters. You can add up'; 'to 3 rear speakers. Initially the rear speakers are deactivated by default. You can turn them on by clicking on its respective'; 'switch. Every speaker you activate it appears on the binaural mixer below by turning the channel''s lamp to green. In the mixer '; 'you can edit its gain level.'; ''; 'v) Play a file:'; 'After the file load and settings application you can hear your audio file by clicking on the play button on Control File section.'; 'When you click play button while your file is playing it stops it and begin to play from start. With stop button you can stop the'; 'file playing. It is recomended to stop any playing audio before you close the application. On the last channel of your binaural'; 'mixer you can change the master volume of your output.'; ''; 'vi) Save your output:'; 'If you want to save an audio file with all the changes you''ve adjusted via the app you can click on the third button on the Control'; 'File section. The file will be saved as wav.'; ''; 'vii) Save your settings:'; 'When you want to keep a set of settings for a future section with the app you can click on the fourth button (heart button) on top'; 'of the screen and the settings will be saved as a txt file. Later you can load them as described above.'; ''; 'LISCENSE'; '--------------------------------------------------------------------------------------------------------'; '"Mint Room" is a software developed for my thesis under the subject "Audio Signals Analysis for Listener''s Envelopement '; 'Estimation". It''s developement was supervised by Professor of Electrical & Computer Engineering, John Mourjopoulos and'; 'Audiogroup of Patras University. Any suggestions or questions on this software are welcome.'; ''; ''; ''; 'Enjoy Mint Room'; 'Thank you.'; ''};
            app.GotitButton = uibutton(app.HelpPanel, 'push');
            app.GotitButton.ButtonPushedFcn = createCallbackFcn(app, @GotitButtonPushed, true);
            app.GotitButton.Text = 'Got it';
            % Show the figure after all components are created
            app.MintRoomUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = greenroom

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.MintRoomUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.MintRoomUIFigure)
        end
    end
end